classdef start_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ComputerVisionChallengeGroup16Label  matlab.ui.control.Label
        StereoinputPanel               matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        OutputstreamPanel              matlab.ui.container.Panel
        UIAxes_2                       matlab.ui.control.UIAxes
        InstructionsPanel              matlab.ui.container.Panel
        Step1SelectPathsPanel          matlab.ui.container.Panel
        EditField                      matlab.ui.control.EditField
        EditField_2                    matlab.ui.control.EditField
        SourceButton                   matlab.ui.control.Button
        BackgroundButton               matlab.ui.control.Button
        OutputButton                   matlab.ui.control.Button
        EditField_3                    matlab.ui.control.EditField
        ClickhereifBackgroundisvideoCheckBox  matlab.ui.control.CheckBox
        Step4StartRenderingPanel       matlab.ui.container.Panel
        RunProgramButton               matlab.ui.control.Button
        StorevideoCheckBox             matlab.ui.control.CheckBox
        Step3SelectRenderingModeButtonGroup  matlab.ui.container.ButtonGroup
        foregroundButton               matlab.ui.control.ToggleButton
        backgroundButton               matlab.ui.control.ToggleButton
        overlayButton                  matlab.ui.control.ToggleButton
        substituteButton               matlab.ui.control.ToggleButton
        Step5ControlOutputstreamPanel  matlab.ui.container.Panel
        StartButton                    matlab.ui.control.StateButton
        Button_2                       matlab.ui.control.StateButton
        Button_3                       matlab.ui.control.StateButton
        Step2SelectParametersPanel     matlab.ui.container.Panel
        RightCamDropDownLabel          matlab.ui.control.Label
        RightCamDropDown               matlab.ui.control.DropDown
        LeftCamDropDownLabel           matlab.ui.control.Label
        LeftCamDropDown                matlab.ui.control.DropDown
        StartEditFieldLabel            matlab.ui.control.Label
        StartEditField                 matlab.ui.control.NumericEditField
        NEditFieldLabel                matlab.ui.control.Label
        NEditField                     matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        selectedPath                 % source path : src variable
        selectedPathBo               % background path : bg variable
        selectedOutputPath           % output path : dest variable
        LeftCam                      % left camera : L variable
        RightCam                     % right camera : R variable
        N                            % nbr of images : N variable
        StartValue                   % start value : start variable
        RenderMode = 'foreground';   % rendering mode : render_mode variable (default = 'foreground')
        ren_tensor                   % tensor of rendered images
        back_slash                   % looks for slashes in entered texts
        right_tensor                 % left tensor : left variable
        left_tensor                  % right tensor : right variable
        check                        % stores the checked box state (if the background is a video)
        store_vid                    % stores the checked box state (if the output video will be stored)
    end


    methods (Access = private)
        
        function [] = bgImageRendering(app)
            
            % This function renders the loaded sequence of images while
            % using the given background image 
            % it will be executed instead of running config.m and
            % challenge.m 
            
            % start timer
            tStart = tic;
            f = uifigure;
            % start a GUI dialog box
            d = uiprogressdlg(f,'Title','Rendering Video',...
                'Indeterminate','on');
            pause(5)
            d.Message = 'please wait';
            % create object ir of the class ImageReader
            ir = ImageReader(string(app.selectedPath), app.LeftCam, app.RightCam, app.StartValue, app.N);
            %  get the variable bg stored in the workspace
            bg = imread(evalin("base","bg"));
            %  get the variable render_mode from the GUI and stores it in the
            %  workspace
            render_mode = app.RenderMode;
            assignin('base',"render_mode",render_mode);
            % get store variable from GUI entry
            store = app.store_vid;
            % initialization of variables and tensors
            n = 0;
            loop= 0;
            ren_im = {};
            left_input = {};
            right_input = {};
            masks = {};
            % while loop : reads sequence images from both cameras, renders them and gives tensor
            % ren_im as result
            while loop ~= 1
                n = n+1;
                % Get next image tensors
                [left,right,loop]= ir.next();
                left_input = [left_input, left{1}];
                right_input = [right_input right{1}];
                ir.start = ir.start + ir.N;
                % Generate binary mask
                mask = segmentation(left,right);
                masks = [masks, mask];
                % Render new frame
                ren_im{end+1} = render(left{1},mask,bg,render_mode); 
            end
            
            % store all tensors in the app properties for further use 
            app.ren_tensor = ren_im;
            app.left_tensor = left_input;
            app.right_tensor = right_input;
            % store output path
            dest = "output.avi";
            %% Write Movie to Disk
            if store
                v = VideoWriter(dest,'Motion JPEG AVI');
                v.FrameRate = 5;
                % open the video writer
                open(v);
                % write the frames to the video
                for u=1:length(ren_im)
                    % convert the image to a frame
                    frame = im2frame(ren_im{u});
                    writeVideo(v, frame);
                end
                % close the writer object
                close(v);
            end
            % stop timer
            tEnd = toc(tStart)  ;
            d.Message= strcat('Elapsed time = ' , num2str(tEnd));
            pause(5);
            % close the GUI dialog
            close(d);
            delete(f);
        end
        
        function [] = bgVideoRendering(app)
            
            % This function renders the loaded sequence of images while
            % using the given background video 
            % it will be executed instead of running config.m and
            
            % start timer
            tStart2 = tic;
            f = uifigure;
            % start GUI dialog
            d = uiprogressdlg(f,'Title','Rendering Video',...
                'Indeterminate','on');
            pause(5)
            d.Message = 'please wait';
            % create ir object of class ImageReader
            ir = ImageReader(string(app.selectedPath), app.LeftCam, app.RightCam, app.StartValue, app.N);
            % load bg variable of the workspace
            bg = evalin("base","bg");
            % get store variable from GUI entry
            store = app.store_vid;
            % initialization of all variables and tensors
            n = 0;
            loop= 0;
            ren_im = {};
            left_input = {};
            right_input = {};
            masks = {}; 
            % while loop : reads sequence images from both cameras, renders
            % them using the video background and gives tensor ren_m as a result
            while loop ~= 1
                n = n+1;
                % Get next image tensors
                [left,right,loop]= ir.next();
                left_input = [left_input, left{1}];
                right_input = [right_input right{1}];
                ir.start = ir.start + ir.N;
                % Generate binary mask
                mask = segmentation(left,right);
                masks = [masks mask];
                
                
            end
            % Render new frame
                ren_im = renderVideo(left_input, masks, bg)
            % store all tensors in the app properties for further use 
            app.ren_tensor = ren_im;
            app.left_tensor = left_input;
            app.right_tensor = right_input;
            % get the video output path
            dest = "output.avi";
            %% Write Movie to Disk
            if store
                v = VideoWriter(dest,'Motion JPEG AVI');
                v.FrameRate = 5;
                % open the video writer
                open(v);
                % write the frames to the video
                for u=1:length(ren_im)
                    % convert the image to a frame
                    frame = im2frame(ren_im{u});
                    writeVideo(v, frame);
                end
                % close the writer object
                close(v);
            end
            % stop timer
            tEnd2 = toc(tStart2)  ;
            d.Message= strcat('Elapsed time = ' , num2str(tEnd2));
            pause(5);
            % close GUI dialog
            close(d);
            delete(f);
        end
        function path = resize_string(app,text,Editfield)
            % This function resizes selected path and shows its last part
            % in the text field
            text=char(text);
            % check if there is \ in the given text
            app.back_slash = contains(text,'\');
            path=text;
            checkPath = length(text)>Editfield.Position(3)/6;
            if(checkPath)
                while(checkPath)
                    splitted_string = split(text,["/","\"]);
                    % get the last part of the text
                    if(~app.back_slash)
                        text= char(join(splitted_string(2:length(splitted_string)),"/"));
                    else
                        text= char(join(splitted_string(2:length(splitted_string)),"\"));
                    end
                    checkPath = length(text)>Editfield.Position(3)/6.2;
                end
                % show the last part only
                if(app.back_slash)
                    path= strcat('..\',text) ;
                else
                    path= strcat('../',text) ;
                end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: 
        % Step3SelectRenderingModeButtonGroup
        function Step3SelectRenderingModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.Step3SelectRenderingModeButtonGroup.SelectedObject;
            %  render mode input : render_mode
            app.RenderMode = selectedButton.Text;
            % store the value in the workspace
            assignin('base',"render_mode", app.RenderMode);
            
            
        end

        % Button pushed function: RunProgramButton
        function RunProgramButtonPushed(app, event)
            % check if all entries are filled
            validInputs = isempty(app.EditField.Value)||isempty(app.EditField_2.Value)||isempty(app.EditField_3.Value)||strcmp(app.RightCamDropDown.Value, 'None')||strcmp(app.LeftCamDropDown.Value,'None');
            if(validInputs)           
               msgbox('Please enter all necessary settings !','Error','error'); 
            else
                % if the box of Background Video is checked
                if app.check == true
                    app.bgVideoRendering();
                % if the rendering will be using background image
                else
                    app.bgImageRendering()
                end
            end
            
        end

        % Button pushed function: SourceButton
        function SourceButtonPushed(app, event)
            % browse dataset path
            app.selectedPath = uigetdir();
            figure(app.UIFigure);
            % show the last part of the path only
            app.EditField_2.Value = resize_string(app,app.selectedPath,app.EditField_2);
            % store the path src in the workspace
            assignin('base',"src", app.selectedPath);
        end

        % Button pushed function: BackgroundButton
        function BackgroundButtonPushed(app, event)
            % browse background image/video
            [file,app.selectedPathBo] = uigetfile({'.png';'.jpg';'*.jpeg';'.mp4'});
            figure(app.UIFigure);
            % show the last part of the path only
            app.EditField.Value = resize_string(app,fullfile(app.selectedPathBo,file),app.EditField);
            path = fullfile(app.selectedPathBo, file);
            % store the path bg in the workspace
            assignin("base","bg",path);
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            
            value = app.StartButton.Value;
            % change the value of other buttons stop/loop
            app.Button_2.Value = false;
            app.Button_3.Value = false;
            global numFrames stop loop play 
            % stop : gives the state of stop button 
            % loop : gives the state of loop button
            % play : gives the state of start button
            stop = false;
            loop = false;
            play = true;
            % get the rendered / left / right frames tensors 
            v = app.ren_tensor;
            leftInput = app.left_tensor;
            rightInput = app.right_tensor;
            % store the nbr of frames 
            numFrames = length(v);
            i=1;
            while(i<numFrames)
                % Display image and stretch to fill axes
                frameVid =  v{i};
                frameLeft =  leftInput{i};
                frameRight =  rightInput{i};
                % show the left input video
                IinputLeft= imshow(frameLeft, 'Parent', app.UIAxes_3, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                % Set limits of axes
                app.UIAxes.XLim = [0 IinputLeft.XData(2)];
                app.UIAxes.YLim = [0 IinputLeft.YData(2)];
                 % show the right input video
                IinputRight= imshow(frameRight, 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes_3.Position(3)], ...
                    'YData', [1 app.UIAxes_3.Position(4)]);
                % Set limits of axes
                app.UIAxes_3.XLim = [0 IinputRight.XData(2)];
                app.UIAxes_3.YLim = [0 IinputRight.YData(2)];
                % show the output video
                Ioutput= imshow(frameVid, 'Parent', app.UIAxes_2, ...
                    'XData', [1 app.UIAxes_2.Position(3)], ...
                    'YData', [1 app.UIAxes_2.Position(4)]);
                % Set limits of axes
                app.UIAxes_2.XLim = [0 Ioutput.XData(2)];
                app.UIAxes_2.YLim = [0 Ioutput.YData(2)];
                drawnow
                
                % Stop playing as long as stop button is pressed
                if stop == true
                    while(1)
                        pause(0.001);
                        if stop == false
                            break;
                        end
                    end
                end
                % stop playing if loop button is pressed
                if loop == true
                    break;
                end
                % increment iterator
                i = i+1;
            end
        end

        % Size changed function: Step5ControlOutputstreamPanel
        function Step5ControlOutputstreamPanelSizeChanged(app, event)
            position = app.Step5ControlOutputstreamPanel.Position;
            
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            % if the stop button is pressed both of start and loop buttons
            % will be disabled and outputstream stopped
            global stop
            value = app.Button_2.Value;
            % change the value of other buttons start/loop
            app.StartButton.Value = false ;
            app.Button_3.Value = false ;
            % change the button icon depending on its value (true/false)
            if(value)
                app.Button_2.Icon = "stop.png";
            else
                app.Button_2.Icon = "play.png";
            end
            % change the value of stop variable when the button value is
            % changed
            if stop==true
                stop=false;
            else
                stop=true;
            end
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            % The loop button will play the sequence in a loop 
            % the stop and start buttons can stopthe loop 
            global numFrames stop loop play
            value = app.Button_3.Value;
            % change the value of other buttons start/stop
            app.StartButton.Value = false ;
            app.Button_2.Value = false ;
            % set the other global variables stop and play to false
            loop = true ;
            stop = false;
            play = false;
            % get the rendered/ left/ right tensors of frames
            v = app.ren_tensor;
            leftInput = app.left_tensor;
            rightInput = app.right_tensor;
            % get the nbr of frames
            numFrames = length(v);
            while(1)
                for iter =1: numFrames-1
                    % Display image and stretch to fill axes
                    frameVid = v{iter};
                    frameLeft = leftInput{iter};
                    frameRight = rightInput{iter};
                    
                    % show the left input video
                    IinputRight= imshow(frameRight, 'Parent', app.UIAxes, ...
                        'XData', [1 app.UIAxes.Position(3)], ...
                        'YData', [1 app.UIAxes.Position(4)]);
                    % Set limits of axes
                    app.UIAxes.XLim = [0 IinputLeft.XData(2)];
                    app.UIAxes.YLim = [0 IinputLeft.YData(2)];
                    % show the right input video
                    IinputLeft= imshow(frameLeft, 'Parent', app.UIAxes_3, ...
                        'XData', [1 app.UIAxes_3.Position(3)], ...
                        'YData', [1 app.UIAxes_3.Position(4)]);
                    % Set limits of axes
                    app.UIAxes_3.XLim = [0 IinputRight.XData(2)];
                    app.UIAxes_3.YLim = [0 IinputRight.YData(2)];
                    % show the output video
                    Ioutput= imshow(frameVid, 'Parent', app.UIAxes_2, ...
                        'XData', [1 app.UIAxes_2.Position(3)], ...
                        'YData', [1 app.UIAxes_2.Position(4)]);
                    % Set limits of axes
                    app.UIAxes_2.XLim = [0 Ioutput.XData(2)];
                    app.UIAxes_2.YLim = [0 Ioutput.YData(2)];
                    drawnow
                    % Stop playing if stop button is pushed 
                    if stop == true
                        while(1)
                            pause(0.001);
                            if stop == false
                                break;
                            end
                        end
                    end
                    % stop playing the loop if the start button is pushed
                    if play == true
                        break;
                    end
                end
            end
        end

        % Value changed function: RightCamDropDown
        function RightCamDropDownValueChanged2(app, event)
   
            app.RightCam = app.RightCamDropDown.Value;
            % check if the R value is numeric and different from 'None'
            valid = isnumeric(str2num(app.RightCam));
            if(valid == false) || (strcmp(app.RightCam, 'None') == 1)
                msgbox('invalid Input : Right Camera !','Error',"error");
            else
                drawnow;
                % store the R variable is the workspace
                assignin('base',"R", str2num(app.RightCam));
            end
        
        end

        % Value changed function: LeftCamDropDown
        function LeftCamDropDownValueChanged2(app, event)
            app.LeftCam = app.LeftCamDropDown.Value;
            % check if the L value is numeric and different from 'None'
            valid = isnumeric(str2num(app.LeftCam));
            if(valid == false) || (strcmp(app.LeftCam, 'None') == 1)
                 msgbox('invalid Input : Left Camera !','Error',"error");
            else
                drawnow;
                 % store the L variable is the workspace
                assignin('base',"L", str2num(app.LeftCam));
            end
        end

        % Button pushed function: OutputButton
        function OutputButtonPushed(app, event)
            % browse output path 
            app.selectedOutputPath = uigetdir();
            figure(app.UIFigure);
            % show the last part of the path only
            app.EditField_3.Value = resize_string(app,app.selectedOutputPath,app.EditField_3);
            % store the destination of output on the workspace
            assignin('base',"dest", app.selectedOutputPath);
        end

        % Value changed function: StartEditField
        function StartEditFieldValueChanged2(app, event)
            % get start variable
            app.StartValue = app.StartEditField.Value;
            valid = isnumeric(app.StartValue);
            % check if the value is numeric and >= 0
            if(valid == false) || (app.StartValue < 0)
                % show error message if not
                msgbox('invalid Input : start value !','Error',"error");
            else
                drawnow;
                % store the value in the workspace
                assignin('base',"start", app.StartValue);
            end
            
               
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged2(app, event)
             %  Number of frames input : N
            app.N = app.NEditField.Value;
            % store the value in the workspace
            assignin('base',"N", app.N);
            
        end

        % Value changed function: StorevideoCheckBox
        function StorevideoCheckBoxValueChanged(app, event)
            % store the store variable(if the output video is stored) from the box 
            value = app.StorevideoCheckBox.Value;
            app.store_vid = value;
        end

        % Value changed function: 
        % ClickhereifBackgroundisvideoCheckBox
        function ClickhereifBackgroundisvideoCheckBoxValueChanged(app, event)
             % store the check variable(if the background is a video) from the box 
            value = app.ClickhereifBackgroundisvideoCheckBox.Value;
            app.check = value;
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ComputerVisionChallengeGroup16Label = uilabel(app.UIFigure);
            app.ComputerVisionChallengeGroup16Label.Text = 'Computer Vision Challenge : Group 16';
            app.StereoinputPanel = uipanel(app.UIFigure);
            app.StereoinputPanel.Title = 'Stereoinput';
            app.UIAxes = uiaxes(app.StereoinputPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_3 = uiaxes(app.StereoinputPanel);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.OutputstreamPanel = uipanel(app.UIFigure);
            app.OutputstreamPanel.Title = 'Outputstream';
            app.UIAxes_2 = uiaxes(app.OutputstreamPanel);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.InstructionsPanel = uipanel(app.UIFigure);
            app.InstructionsPanel.Title = 'Instructions:';
            app.Step1SelectPathsPanel = uipanel(app.InstructionsPanel);
            app.Step1SelectPathsPanel.Title = 'Step 1 : Select Paths ';
            app.EditField = uieditfield(app.Step1SelectPathsPanel, 'text');
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.Step1SelectPathsPanel, 'text');
            app.EditField_2.Editable = 'off';
            app.SourceButton = uibutton(app.Step1SelectPathsPanel, 'push');
            app.SourceButton.ButtonPushedFcn = createCallbackFcn(app, @SourceButtonPushed, true);
            app.SourceButton.Text = 'Source ';
            app.BackgroundButton = uibutton(app.Step1SelectPathsPanel, 'push');
            app.BackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @BackgroundButtonPushed, true);
            app.BackgroundButton.Text = 'Background';
            app.OutputButton = uibutton(app.Step1SelectPathsPanel, 'push');
            app.OutputButton.ButtonPushedFcn = createCallbackFcn(app, @OutputButtonPushed, true);
            app.OutputButton.Text = 'Output ';
            app.EditField_3 = uieditfield(app.Step1SelectPathsPanel, 'text');
            app.EditField_3.Editable = 'off';
            app.ClickhereifBackgroundisvideoCheckBox = uicheckbox(app.Step1SelectPathsPanel);
            app.ClickhereifBackgroundisvideoCheckBox.ValueChangedFcn = createCallbackFcn(app, @ClickhereifBackgroundisvideoCheckBoxValueChanged, true);
            app.ClickhereifBackgroundisvideoCheckBox.Text = {'Click here if Background is video'; ''};
            app.Step4StartRenderingPanel = uipanel(app.InstructionsPanel);
            app.Step4StartRenderingPanel.Title = 'Step 4 : Start Rendering';
            app.RunProgramButton = uibutton(app.Step4StartRenderingPanel, 'push');
            app.RunProgramButton.ButtonPushedFcn = createCallbackFcn(app, @RunProgramButtonPushed, true);
            app.RunProgramButton.Text = 'Run Program';
            app.StorevideoCheckBox = uicheckbox(app.Step4StartRenderingPanel);
            app.StorevideoCheckBox.ValueChangedFcn = createCallbackFcn(app, @StorevideoCheckBoxValueChanged, true);
            app.StorevideoCheckBox.Text = 'Store video';
            app.Step3SelectRenderingModeButtonGroup = uibuttongroup(app.InstructionsPanel);
            app.Step3SelectRenderingModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Step3SelectRenderingModeButtonGroupSelectionChanged, true);
            app.Step3SelectRenderingModeButtonGroup.Title = 'Step 3 : Select Rendering Mode';
            app.foregroundButton = uitogglebutton(app.Step3SelectRenderingModeButtonGroup);
            app.foregroundButton.Text = 'foreground';
            app.foregroundButton.Value = true;
            app.backgroundButton = uitogglebutton(app.Step3SelectRenderingModeButtonGroup);
            app.backgroundButton.Text = 'background';
            app.overlayButton = uitogglebutton(app.Step3SelectRenderingModeButtonGroup);
            app.overlayButton.Text = 'overlay';
            app.substituteButton = uitogglebutton(app.Step3SelectRenderingModeButtonGroup);
            app.substituteButton.Text = 'substitute';
            app.Step5ControlOutputstreamPanel = uipanel(app.InstructionsPanel);
            app.Step5ControlOutputstreamPanel.Title = 'Step 5 : Control Outputstream';
            app.Step5ControlOutputstreamPanel.SizeChangedFcn = createCallbackFcn(app, @Step5ControlOutputstreamPanelSizeChanged, true);
            app.StartButton = uibutton(app.Step5ControlOutputstreamPanel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.Button_2 = uibutton(app.Step5ControlOutputstreamPanel, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Icon = 'stop.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.Step5ControlOutputstreamPanel, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Icon = 'loop.png';
            app.Button_3.Text = '';
            app.Step2SelectParametersPanel = uipanel(app.InstructionsPanel);
            app.Step2SelectParametersPanel.Title = 'Step 2 : Select Parameters';
            app.RightCamDropDownLabel = uilabel(app.Step2SelectParametersPanel);
            app.RightCamDropDownLabel.Text = 'Right Cam';
            app.RightCamDropDown = uidropdown(app.Step2SelectParametersPanel);
            app.RightCamDropDown.Items = {'None', '2', '3'};
            app.RightCamDropDown.ValueChangedFcn = createCallbackFcn(app, @RightCamDropDownValueChanged2, true);
            app.RightCamDropDown.Value = 'None';
            app.LeftCamDropDownLabel = uilabel(app.Step2SelectParametersPanel);
            app.LeftCamDropDownLabel.Text = 'Left Cam';
            app.LeftCamDropDown = uidropdown(app.Step2SelectParametersPanel);
            app.LeftCamDropDown.Items = {'None', '1', '2'};
            app.LeftCamDropDown.ValueChangedFcn = createCallbackFcn(app, @LeftCamDropDownValueChanged2, true);
            app.LeftCamDropDown.Value = 'None';
            app.StartEditFieldLabel = uilabel(app.Step2SelectParametersPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.Step2SelectParametersPanel, 'numeric');
            app.StartEditField.Limits = [0 Inf];
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @StartEditFieldValueChanged2, true);
            app.StartEditField.Value = 1;
            app.NEditFieldLabel = uilabel(app.Step2SelectParametersPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.Step2SelectParametersPanel, 'numeric');
            app.NEditField.Limits = [1 Inf];
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged2, true);
            app.NEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = start_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end